classdef main < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                   matlab.ui.container.TabGroup
        KonvolusiTab               matlab.ui.container.Tab
        KonvolusiBlurButton        matlab.ui.control.Button
        PilihKonvolusiLabel        matlab.ui.control.Label
        PilihFileKonvolusiButton   matlab.ui.control.Button
        SmoothingTab               matlab.ui.container.Tab
        BLPFButton                 matlab.ui.control.Button
        GLPFButton                 matlab.ui.control.Button
        SpasialGaussianBlurButton  matlab.ui.control.Button
        ILPFButton                 matlab.ui.control.Button
        SpasialMeanBlurButton      matlab.ui.control.Button
        PilihSmoothingLabel        matlab.ui.control.Label
        PilihFileSmoothing         matlab.ui.control.Button
        FHPFTab                    matlab.ui.container.Tab
        BHPFButton                 matlab.ui.control.Button
        GHPFButton                 matlab.ui.control.Button
        IHPFButton                 matlab.ui.control.Button
        PilihFrequencyLabel        matlab.ui.control.Label
        PilihFileFrequency         matlab.ui.control.Button
        LightenTab                 matlab.ui.container.Tab
        HighBoost                  matlab.ui.control.Button
        PilihLightenLabel          matlab.ui.control.Label
        PilihFileLighten           matlab.ui.control.Button
        MedianTab                  matlab.ui.container.Tab
        Median                     matlab.ui.control.Button
        PilihMedianLabel           matlab.ui.control.Label
        PilihFileMedian            matlab.ui.control.Button
        PeriodikTab                matlab.ui.container.Tab
        GambarD                    matlab.ui.control.Button
        GambarC                    matlab.ui.control.Button
        GambarB                    matlab.ui.control.Button
        GambarA                    matlab.ui.control.Button
        PilihPeriodikLabel         matlab.ui.control.Label
        PilihFilePeriodik          matlab.ui.control.Button
    end

    methods (Access = public)
        function res = Konvolusi(~, image, kernel)
            [numRow, numColumn, numColor] = size(image);
            res = zeros([numRow, numColumn, numColor]);
            [kernelSize, ~] = size(kernel);
            offsetK = floor(kernelSize / 2);

            boundLower = 1 + offsetK;
            rowUpper = numRow - offsetK;
            colUpper = numColumn - offsetK;

            for i = boundLower:rowUpper
                for j = boundLower:colUpper
                    for c = 1:numColor
                        temp = single(0);
                        for a = -offsetK:offsetK
                            for b = -offsetK:offsetK
                                temp = temp + single(image(i+a, j+b, c)) * kernel(1+offsetK+a, 1+offsetK+b);
                            end
                        end
                        res(i, j, c) = temp;
                        res(i, j, c) = max(0, min(255, res(i, j, c)));
                    end
                end
            end

            res = uint8(res);
        end

        function res = ApplyFrequencyFilter(~, image, mask)
            % Initial image
            figure("Name", "Citra Input")
            imshow(image);

            % Pad
            [M, N, c] = size(image);
            padded = uint8(zeros(2*M, 2*N, c));

            for i = 1:2*M
                for j = 1:2*N
                    for a = 1:c
                      if i <= M && j <= N
                            padded(i, j, a) = image(i, j, a);
                      else
                            padded(i, j, a) = 0;
                      end
                    end
                end
            end
            figure;
            imshow(padded);

            % FT
            Fc = fftshift(fft2(im2double(padded)));
            S2 = log(1+abs(Fc));
            figure, imshow(S2, []);
            
            F = fft2(im2double(padded));

            % Mask Application
            H = mask;
            H = fftshift(H); figure;imshow(H);title('LPF Ideal Mask');
            figure, mesh(H);

            % Applying Mask
            H = ifftshift(H);
            FF_f = H.*F;

            FF_f2 = real(ifft2(FF_f));
            figure; imshow(FF_f2); title("Padded Result");

            FF_f2 = FF_f2(1:M, 1:N, :);
            figure, imshow(FF_f2); title("End Result");

            res = FF_f2;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

        end

        % Button pushed function: PilihFileKonvolusiButton
        function KonvolusiFileButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihKonvolusiLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: KonvolusiBlurButton
        function JalankanKonvolusi(comp, event)
            try
                image = imread(comp.PilihKonvolusiLabel.Text);
                
                % Initial image
                figure("Name", "Citra Input")
                imshow(image);
                
                % Konvolusi
                kernel = [0.12 0.12 0.12; 0.12 0.12 0.12; 0.12 0.12 0.12];
                res = comp.Konvolusi(image, kernel);
                figure("Name", "Citra Hasil")
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: PilihFileSmoothing
        function SmoothingButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihSmoothingLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: SpasialMeanBlurButton
        function JalankanMeanFilter(comp, event)
            try
                image = imread(comp.PilihSmoothingLabel.Text);
             
                % Initial image
                figure("Name", "Citra Input")
                imshow(image);

                % Konvolusi
                kernel = [0.12 0.12 0.12; 0.12 0.12 0.12; 0.12 0.12 0.12];
                res = comp.Konvolusi(image, kernel);
                figure("Name", "Citra Hasil")
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: SpasialGaussianBlurButton
        function JalankanGauss(comp, event)
            try
                image = imread(comp.PilihSmoothingLabel.Text);
             
                % Initial image
                figure("Name", "Citra Input")
                imshow(image);

                % Konvolusi
                kernel = [0.0625 0.125 0.0625; 0.125 0.25 0.125; 0.0625 0.125 0.0625];
                res = comp.Konvolusi(image, kernel);
                figure("Name", "Citra Hasil")
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: ILPFButton
        function JalankanILPF(comp, event)
            try
                image = imread(comp.PilihSmoothingLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                H = double(D <= D0);
                comp.ApplyFrequencyFilter(image, H);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: GLPFButton
        function JalankanGLPF(comp, event)
             try
                image = imread(comp.PilihSmoothingLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                H = exp(-(D.^2)./(2*(D0^2)));
                comp.ApplyFrequencyFilter(image, H);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: BLPFButton
        function JalankanBLPF(comp, event)
             try
                image = imread(comp.PilihSmoothingLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                n = 2;
                H = 1./(1 + (D./D0).^(2*n));
                comp.ApplyFrequencyFilter(image, H);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: PilihFileFrequency
        function FrequencyFilterButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihFrequencyLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: IHPFButton
        function JalankanIHPF(comp, event)
             try
                image = imread(comp.PilihFrequencyLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                H = double(D <= D0);
                H = 1 - H;
                comp.ApplyFrequencyFilter(image, H);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: GHPFButton
        function JalankanGHPF(comp, event)
             try
                image = imread(comp.PilihFrequencyLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                H = exp(-(D.^2)./(2*(D0^2)));
                H = 1 - H;
                comp.ApplyFrequencyFilter(image, H);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: BHPFButton
        function JalankanBHPF(comp, event)
             try
                image = imread(comp.PilihFrequencyLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                n = 2;
                H = 1./(1 + (D./D0).^(2*n));
                H = 1 - H;
                comp.ApplyFrequencyFilter(image, H);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: PilihFileLighten
        function LightenButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihLightenLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: HighBoost
        function JalankanFreqHB(comp, event)
             try
                image = imread(comp.PilihLightenLabel.Text);
                [M, N, ~] = size(image);
                D0 = 50;
                u = 0:(2*M-1);
                v = 0:(2*N-1);
    
                idx = find(u > M);
                u(idx) = u(idx) - 2*M;
                idy = find(v > N);
                v(idy) = v(idy) - 2*N;
                
                [V, U] = meshgrid(v, u);
                D = sqrt(U.^2 + V.^2);
                n = 2;
                H = 1./(1 + (D./D0).^(2*n));
                BLPFSource = comp.ApplyFrequencyFilter(image, H);
                hbimage = 1.2*image - uint8(BLPFSource);

                figure, imshow(hbimage); title("High Boost Result");
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: PilihFileMedian
        function MedianButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihMedianLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: Median
        function JalankanMedian(comp, event)
             try
                image = imread(comp.PilihMedianLabel.Text);
                figure, imshow(image); title("Citra Input");
                noised = imnoise(image, "salt & pepper", 0.1);
                figure, imshow(noised); title("Salt & Pepper Image");
                
                [row, col, c] = size(image);
                res = zeros([row, col, c]);
                for i = 2:row-1
                    for j = 2:col-1
                        for c = 1:c
                            temp = zeros([3, 3]);
                            for a = -1:1
                                for b = -1:1
                                    temp(2 + a, 2 + b) = image(i+a, j+b, c);
                                end
                            end
                            res(i, j, c) = median(reshape(temp.', 1, []));
                            res(i, j, c) = max(0, min(255, res(i, j, c)));
                        end
                    end
                end
                res = uint8(res);

                figure, imshow(res); title("Median Filter Image");
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: PilihFilePeriodik
        function PeriodikButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihPeriodikLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: GambarA
        function JalankanGambarA(comp, event)
             try
                image = imread(comp.PilihPeriodikLabel.Text);
                image = image(:, :, 2);
                figure, imshow(image); title("Citra Input");
                
                F1 = fftshift(fft2(im2double(image)));
                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum");

                for j = 145:155
                    for i = 270:275
                        F1(i, j) = 0;
                    end
                    for i = 145:155
                        F1(i, j) = 0;
                    end
                    for i = 20:30
                        F1(i, j) = 0;
                    end
                end

                for j = 125:135
                    for i = 250:255
                        F1(i, j) = 0;
                    end
                    for i = 125:135
                        F1(i, j) = 0;
                    end
                    for i = 1:10
                        F1(i, j) = 0;
                    end
                end

                for i = 125:135
                    for j = 250:255
                        F1(i, j) = 0;
                    end
                    for j = 1:10
                        F1(i, j) = 0;
                    end
                end

                for i = 145:155
                    for j = 270:275
                        F1(i, j) = 0;
                    end
                    for j = 20:30
                        F1(i, j) = 0;
                    end
                end

                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum Perbaikan");

                res = real(ifft2(ifftshift(F1)));
                figure, imshow(res); title("Hasil");
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: GambarB
        function JalankanGambarB(comp, event)
            try
                image = imread(comp.PilihPeriodikLabel.Text);
                image = image(:, :, 2);
                figure, imshow(image); title("Citra Input");
                
                F1 = fftshift(fft2(im2double(image)));
                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum");

                for j = 155:185
                    for i = 180:200
                        F1(i, j) = 0;
                    end
                end

                for j = 400:440
                    for i = 160:200
                        F1(i, j) = 0;
                    end
                end

                for i = 380:400
                    for j = 410:440
                        F1(i, j) = 0;
                    end
                end

                for i = 380:400
                    for j = 160:180
                        F1(i, j) = 0;
                    end
                end

                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum Perbaikan");

                res = real(ifft2(ifftshift(F1)));
                figure, imshow(res); title("Hasil");
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end

        % Button pushed function: GambarC
        function JalankanGambarC(comp, event)
            try
                image = imread(comp.PilihPeriodikLabel.Text);
                image = image(:, :, 2);
                figure, imshow(image); title("Citra Input");
                
                F1 = fftshift(fft2(im2double(image)));
                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum");

                for nudge = 0:5
                    for i = 230-nudge*15:240-nudge*15
                        for j = 45+nudge*15:55+nudge*15
                            F1(i, j) = 0;
                        end
                    end
                end

                for nudge = 0:5
                    for i = 120-nudge*15:130-nudge*15
                        for j = 155+nudge*15:165+nudge*15
                            F1(i, j) = 0;
                        end
                    end
                end

                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum Perbaikan");

                res = real(ifft2(ifftshift(F1)));
                figure, imshow(res); title("Hasil");
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end

        % Button pushed function: GambarD
        function JalankanGambarD(comp, event)
            try
                image = imread(comp.PilihPeriodikLabel.Text);
                image = image(:, :, 2);
                figure, imshow(image); title("Citra Input");
                
                F1 = fftshift(fft2(im2double(image)));
                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum");

                for nudge = 0:4
                    for j = 85:95
                        for i = 85+nudge*25:95+nudge*25
                            F1(i, j) = 0;
                        end
                    end
                end

                for nudge = 0:4
                    for j = 280:290
                        for i = 85+nudge*25:95+nudge*25
                            F1(i, j) = 0;
                        end
                    end
                end

                for i = 155:165
                    for j = 180:195
                        F1(i, j) = 0;
                    end
                end

                for i = 110:120
                    for j = 180:195
                        F1(i, j) = 0;
                    end
                end


                F2 = log(1+abs(F1));
                figure, imagesc(100*F2); colormap("gray"); title("Spektrum Perbaikan");

                res = real(ifft2(ifftshift(F1)));
                figure, imshow(res); title("Hasil");
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 506 373];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.KonvolusiTab = uitab(comp.TabGroup);
            comp.KonvolusiTab.Title = 'Konvolusi';
            comp.PilihFileKonvolusiButton = uibutton(comp.KonvolusiTab, 'push');
            comp.PilihFileKonvolusiButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @KonvolusiFileButton, true);
            comp.PilihFileKonvolusiButton.Text = 'Pilih File';
            comp.PilihKonvolusiLabel = uilabel(comp.KonvolusiTab);
            comp.PilihKonvolusiLabel.Text = 'Pilih gambar input....';
            comp.KonvolusiBlurButton = uibutton(comp.KonvolusiTab, 'push');
            comp.KonvolusiBlurButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanKonvolusi, true);
            comp.KonvolusiBlurButton.Text = 'Konvolusi Blur';
            comp.SmoothingTab = uitab(comp.TabGroup);
            comp.SmoothingTab.Title = 'Smoothing';
            comp.PilihFileSmoothing = uibutton(comp.SmoothingTab, 'push');
            comp.PilihFileSmoothing.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SmoothingButton, true);
            comp.PilihFileSmoothing.Text = 'Pilih File';
            comp.PilihSmoothingLabel = uilabel(comp.SmoothingTab);
            comp.PilihSmoothingLabel.Text = 'Pilih gambar input....';
            comp.SpasialMeanBlurButton = uibutton(comp.SmoothingTab, 'push');
            comp.SpasialMeanBlurButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanMeanFilter, true);
            comp.SpasialMeanBlurButton.Text = 'Spasial Mean Blur';
            comp.ILPFButton = uibutton(comp.SmoothingTab, 'push');
            comp.ILPFButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanILPF, true);
            comp.ILPFButton.Text = 'ILPF';
            comp.SpasialGaussianBlurButton = uibutton(comp.SmoothingTab, 'push');
            comp.SpasialGaussianBlurButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGauss, true);
            comp.SpasialGaussianBlurButton.Text = 'Spasial Gaussian Blur';
            comp.GLPFButton = uibutton(comp.SmoothingTab, 'push');
            comp.GLPFButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGLPF, true);
            comp.GLPFButton.Text = 'GLPF';
            comp.BLPFButton = uibutton(comp.SmoothingTab, 'push');
            comp.BLPFButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanBLPF, true);
            comp.BLPFButton.Text = 'BLPF';
            comp.FHPFTab = uitab(comp.TabGroup);
            comp.FHPFTab.Title = 'FHPF';
            comp.PilihFileFrequency = uibutton(comp.FHPFTab, 'push');
            comp.PilihFileFrequency.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FrequencyFilterButton, true);
            comp.PilihFileFrequency.Text = 'Pilih File';
            comp.PilihFrequencyLabel = uilabel(comp.FHPFTab);
            comp.PilihFrequencyLabel.Text = 'Pilih gambar input....';
            comp.IHPFButton = uibutton(comp.FHPFTab, 'push');
            comp.IHPFButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanIHPF, true);
            comp.IHPFButton.Text = 'IHPF';
            comp.GHPFButton = uibutton(comp.FHPFTab, 'push');
            comp.GHPFButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGHPF, true);
            comp.GHPFButton.Text = 'GHPF';
            comp.BHPFButton = uibutton(comp.FHPFTab, 'push');
            comp.BHPFButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanBHPF, true);
            comp.BHPFButton.Text = 'BHPF';
            comp.LightenTab = uitab(comp.TabGroup);
            comp.LightenTab.Title = 'Lighten';
            comp.PilihFileLighten = uibutton(comp.LightenTab, 'push');
            comp.PilihFileLighten.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LightenButton, true);
            comp.PilihFileLighten.Text = 'Pilih File';
            comp.PilihLightenLabel = uilabel(comp.LightenTab);
            comp.PilihLightenLabel.Text = 'Pilih gambar input....';
            comp.HighBoost = uibutton(comp.LightenTab, 'push');
            comp.HighBoost.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanFreqHB, true);
            comp.HighBoost.Text = 'High Boost A=1.2';
            comp.MedianTab = uitab(comp.TabGroup);
            comp.MedianTab.Title = 'Median';
            comp.PilihFileMedian = uibutton(comp.MedianTab, 'push');
            comp.PilihFileMedian.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MedianButton, true);
            comp.PilihFileMedian.Text = 'Pilih File';
            comp.PilihMedianLabel = uilabel(comp.MedianTab);
            comp.PilihMedianLabel.Text = 'Pilih gambar input....';
            comp.Median = uibutton(comp.MedianTab, 'push');
            comp.Median.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanMedian, true);
            comp.Median.Text = 'Median';
            comp.PeriodikTab = uitab(comp.TabGroup);
            comp.PeriodikTab.Title = 'Periodik';
            comp.PilihFilePeriodik = uibutton(comp.PeriodikTab, 'push');
            comp.PilihFilePeriodik.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PeriodikButton, true);
            comp.PilihFilePeriodik.Text = 'Pilih File';
            comp.PilihPeriodikLabel = uilabel(comp.PeriodikTab);
            comp.PilihPeriodikLabel.Text = 'Pilih gambar input....';
            comp.GambarA = uibutton(comp.PeriodikTab, 'push');
            comp.GambarA.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGambarA, true);
            comp.GambarA.Text = 'Gambar A';
            comp.GambarB = uibutton(comp.PeriodikTab, 'push');
            comp.GambarB.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGambarB, true);
            comp.GambarB.Text = 'Gambar B';
            comp.GambarC = uibutton(comp.PeriodikTab, 'push');
            comp.GambarC.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGambarC, true);
            comp.GambarC.Text = 'Gambar C';
            comp.GambarD = uibutton(comp.PeriodikTab, 'push');
            comp.GambarD.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanGambarD, true);
            comp.GambarD.Text = 'Gambar D';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end